classdef vslot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        VSlotsLabel           matlab.ui.control.Label
        CreditEditFieldLabel  matlab.ui.control.Label
        Credit                matlab.ui.control.EditField
        BetEditFieldLabel     matlab.ui.control.Label
        Bet                   matlab.ui.control.EditField
        WinEditFieldLabel     matlab.ui.control.Label
        Castig                matlab.ui.control.EditField
        Spin                  matlab.ui.control.Button
        BoardWin              matlab.ui.control.Button
        Exit                  matlab.ui.control.Button
        Table                 matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UITableDisplayDataChanged(app, event)
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Button pushed function: Exit
        function ExitPushed(app, event)
            app.delete;
        end

        % Button pushed function: BoardWin
        function BoardWinButtonPushed(app, event)
            scor;
        end

        % Button pushed function: Spin
        function SpinButtonPushed(app, event)
            credit=str2num(app.Credit.Value);
            pariu=str2num(app.Bet.Value);
           
            
            if(pariu>0)
                
                
                if(credit>=pariu)
                    
                    %SpinMusic
                    [m, fs] = audioread('Spin.mp3');
                    sound(m,fs);
                    p = audioplayer(m,fs);
                    play(p)
                    %generare matrice-afisaj
                    a=round(1+6*rand(3,3));
                    app.Table.Data=a;
                    ecran=app.Table;
                    s1 = uistyle;
                    s1.BackgroundColor = 'white';
                    addStyle(ecran,s1,'column',[1 2 3])
                    
                    
                    %vector conditii castig
                    castig=zeros(1,8);
                    %Linia 1
                    if(a(1,1)==a(1,2)) && (a(1,2)==a(1,3))
                        castig(1,1)=a(1,1);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'row',[1])
                    end
                    %Linia 2
                    if(a(2,1)==a(2,2)) && (a(2,2)==a(2,3))
                        castig(1,2)=a(2,1);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'row',[2])
                    end
                    %Linia 3
                    if(a(3,1)==a(3,2)) && (a(3,2)==a(3,3))
                        castig(1,3)=a(3,1);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'row',[3])
                    end
                     %{ 
                    
                   %Coloana 1
                    if(a(1,1)==a(2,1)) && (a(2,1)==a(3,1))
                        castig(1,4)=a(1,1);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'column',[1])
                    end
                    %Coloana 2
                    if(a(1,2)==a(2,2)) && (a(2,2)==a(3,2))
                        castig(1,5)=a(1,2);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'column',[2])
                    end
                    %Coloana 3
                    if(a(1,3)==a(2,3)) && (a(2,3)==a(3,3))
                        castig(1,6)=a(1,3);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'column',[3])
                    end
                        %}  
                                    
                    %Diag P
                    if(a(1,1)==a(2,2)) && (a(2,2)==a(3,3))
                        castig(1,7)=a(1,1);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'cell',[1,1])
                        addStyle(ecran,s1,'cell',[2,2])
                        addStyle(ecran,s1,'cell',[3,3])
                    end
                    %Diag S
                    if(a(1,3)==a(2,2)) && (a(2,2)==a(3,1))
                        castig(1,8)=a(1,3);     s1 = uistyle;
                        s1.BackgroundColor = 'red';
                        addStyle(ecran,s1,'cell',[1,3])
                        addStyle(ecran,s1,'cell',[2,2])
                        addStyle(ecran,s1,'cell',[3,1])
                    end
                    %castig
                    ct=0;
                    %win-uri
                    for i=1:8
                        switch castig(i)
                            case 1,ct= ct+4*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 2,ct= ct+8*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 3,ct= ct+8*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 4,ct= ct+16*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 5,ct= ct+30*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 6,ct= ct+60*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win1-6.mp3');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                            case 7,ct= ct+200*(pariu/10);
                                %Muzica
                                [m, fs] = audioread('Win7.wav');
                                sound(m,fs);
                                p = audioplayer(m,fs);
                                play(p)
                        end
                        
                    end
                 
                
                    if(ct>0)
                        credit=credit+ct;
                    else
                        credit=credit-pariu;
                    end
                    
                    app.Credit.Value= num2str(credit);
                    app.Castig.Value= num2str(ct);
                    if(ct>0)
                        head={'','Credit','Bet','Win'}
                        writecell(head,'Castiguri.xls')
                        numb={credit,pariu,ct,}
                        writecell(numb,'Castiguri.xls',"WriteMode","append")
                        
                        
                    end


                else
                    errordlg('Credit Insuficient','Credit');
                end
            else
                
                errordlg('Bet incorect','Bet');
                
            end


        end

        % Cell edit callback: Table
        function TableCellEdit(app, event)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VSlotsLabel = uilabel(app.UIFigure);
            app.VSlotsLabel.Text = '🍀V-Slots🍀';
            app.CreditEditFieldLabel = uilabel(app.UIFigure);
            app.CreditEditFieldLabel.Text = 'Credit';
            app.Credit = uieditfield(app.UIFigure, 'text');
            app.Credit.Editable = 'off';
            app.Credit.Value = '1000';
            app.BetEditFieldLabel = uilabel(app.UIFigure);
            app.BetEditFieldLabel.Text = 'Bet';
            app.Bet = uieditfield(app.UIFigure, 'text');
            app.Bet.Value = '10';
            app.WinEditFieldLabel = uilabel(app.UIFigure);
            app.WinEditFieldLabel.Text = 'Win';
            app.Castig = uieditfield(app.UIFigure, 'text');
            app.Castig.Value = '0';
            app.Spin = uibutton(app.UIFigure, 'push');
            app.Spin.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.Spin.Text = {'Spin'; ''};
            app.BoardWin = uibutton(app.UIFigure, 'push');
            app.BoardWin.ButtonPushedFcn = createCallbackFcn(app, @BoardWinButtonPushed, true);
            app.BoardWin.Text = 'Show Table Board';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.Exit.Text = 'Exit';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'🍀'; '🍀'; '🍀'; '🍀'};
            app.Table.RowName = {};
            app.Table.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vslot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end